classdef Code_23071484 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ScaleValueEditField       matlab.ui.control.NumericEditField
        ScalerSlider              matlab.ui.control.Slider
        ScalerSliderLabel         matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        Transform3CirclesButton   matlab.ui.control.Button
        NumberofCirclesLabel      matlab.ui.control.Label
        NumberofCirclesEditField  matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NumberOfClicks = 0
        coords % Description
    end
    
    methods (Access = private)
        
        function calculateClicks(app)
            circles = app.NumberofCirclesEditField.Value;
            if circles == 0
                app.NumberOfClicks = 0;
            elseif circles == 1
                app.NumberOfClicks = 2;
            elseif circles == 2
                app.NumberOfClicks = 3;
            elseif circles == 3
                app.NumberOfClicks = 6;
            end
        end
        
        function plotcircle(app, x, y, r)
            % Create circle data points
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
        
            % Plot on the app's UIAxes
            plot(app.UIAxes, xunit, yunit);
        end
        
        function results = radiusTangentialCircle(app, x1, y1, x2, y2)
            results = sqrt((x2-x1)^2 + (y2-y1)^2);
        end

        function [xOnCircle, yOnCircle, select] = closestPointsOnCircle(app, xCircle1, yCircle1, radiusCircle1, xCircle2, yCircle2, radiusCircle2, xPoint, yPoint)
            % Generate points for Circle 1 and 2
            th = 0:pi/50:2*pi; % Angle range for the circle
        
            c1xunit = radiusCircle1 * cos(th) + xCircle1;
            c1yunit = radiusCircle1 * sin(th) + yCircle1;
        
            c2xunit = radiusCircle2 * cos(th) + xCircle2;
            c2yunit = radiusCircle2 * sin(th) + yCircle2;
        
            % The point to snap
            point = [xPoint, yPoint];
            c1 = [c1xunit', c1yunit']; % Circle 1 coordinates
            c2 = [c2xunit', c2yunit']; % Circle 2 coordinates
        
            % Find the index of the closest point on Circle 1 and Circle 2
            index1 = dsearchn(c1, point);
            index2 = dsearchn(c2, point);
        
            % Retrieve the closest points' coordinates
            closestPoint1 = c1(index1, :); % [x, y] on Circle 1
            closestPoint2 = c2(index2, :);% Circle 2
        
            % Calculate distances
            distance1 = sqrt((point(1)-closestPoint1(1))^2 + (point(2)-closestPoint1(2))^2);
            distance2 = sqrt((point(1)-closestPoint2(1))^2 + (point(2)-closestPoint2(2))^2);
        
            % Compare distances and determine the closest circle
            if distance1 > distance2
                xOnCircle = closestPoint2(1);
                yOnCircle = closestPoint2(2);
                select = 2; % Circle 2 is closer
            else
                xOnCircle = closestPoint1(1);
                yOnCircle = closestPoint1(2);
                select = 1; % Circle 1 is closer
            end
        end

        
        function [m, c] = centreBisector(app, xCircle1, yCircle1, xCircle2, yCircle2)
            % Midpoint of the line segment
            midX = (xCircle1 + xCircle2) / 2;
            midY = (yCircle1 + yCircle2) / 2;
        
            % Check if the line is vertical
            if xCircle2 == xCircle1
                m = 0; % Horizontal line
                c = midY; % Line is y = c
            elseif yCircle2 == yCircle1
                m = Inf; % Vertical line
                c = midX;
            else
                % Perpendicular slope is the negative reciprocal of the original slope
                m = -1 / ((yCircle2 - yCircle1) / (xCircle2 - xCircle1));
                c = midY - m * midX;
            end
        end

        function [xCircle, yCircle, radiusCircle] = circleSolve(app, p1,p2,p3)

            p1x = p1(1);
            p1y = p1(2);

            p2x = p2(1);
            p2y = p2(2);

            p3x = p3(1);
            p3y = p3(2);


            [m12, c12] = app.centreBisector(p1x, p1y, p2x, p2y);
            [m13, c13] = app.centreBisector(p1x, p1y, p3x, p3y);
            
            % Handle vertical bisectors (m12 or m13 is Inf)
            if isinf(m12)
                xCircle = -c12; % x = constant (vertical line at x = -c12)
                yCircle = m13 * xCircle + c13; % Calculate y using the other bisector
            elseif isinf(m13)
                xCircle = -c13; % x = constant (vertical line at x = -c13)
                yCircle = m12 * xCircle + c12;
            else
                % General case
                xCircle = (c13 - c12) / (m12 - m13);
                yCircle = m12 * xCircle + c12;
            end

            radiusCircle = sqrt((xCircle - p1x)^2 + (yCircle - p1y)^2);
        end
        
        function intersections = intersections2circles(app, radiusCircle1, xCircle1, yCircle1, radiusCircle2, xCircle2, yCircle2)
            % Calculate the distance between the two circle centers
            d = sqrt((xCircle2 - xCircle1)^2 + (yCircle2 - yCircle1)^2);
            
            % Check for no intersection
            if d > (radiusCircle1 + radiusCircle2) || d < abs(radiusCircle1 - radiusCircle2)
                intersections = []; % No intersection
                fig = uifigure;
                uialert(fig, "No intersections", "Intersection Error");
                return;
            end
            
            % Check for infinite intersections (identical circles)
            if d == 0 && radiusCircle1 == radiusCircle2
                intersections = [];
                fig = uifigure;
                uialert(fig, "Infinite intersections (circles are identical).", "Intersection Error");
            end
            
            % Calculate the intersection points
            a = (radiusCircle1^2 - radiusCircle2^2 + d^2) / (2 * d);
            
            h = sqrt(radiusCircle1^2 - a^2);
            
            x0 = xCircle1 + a * (xCircle2 - xCircle1) / d;
            y0 = yCircle1 + a * (yCircle2 - yCircle1) / d;
            
            xIntersect1 = x0 + h * (yCircle2 - yCircle1) / d;
            yIntersect1 = y0 - h * (xCircle2 - xCircle1) / d;
            
            xIntersect2 = x0 - h * (yCircle2 - yCircle1) / d;
            yIntersect2 = y0 + h * (xCircle2 - xCircle1) / d;
            
            intersections = [xIntersect1, xIntersect2; yIntersect1, yIntersect2];
        end

        function [matrixT, matrixR, matrixCombined] = transformationMatrices(app, xCircle1,yCircle1,xCircle2,yCircle2,xCircle3,yCircle3)
            %calculate the tranformation matrix
            matrixT =   [1, 0, -xCircle3; 
                        0, 1, -yCircle3; 
                        0, 0, 1];

            %calculate rotation matrix
            if yCircle2 ~= yCircle1
                m = -(xCircle2 - xCircle1) / (yCircle2 - yCircle1);
                theta = atan(m);
            else
                theta = pi / 2; %vertical line case
            end
            matrixR = [cos(theta), -sin(theta), 0; 
                        sin(theta), cos(theta), 0; 
                        0, 0, 1];

            matrixCombined = matrixR * matrixT;
        end


        function [xOnBisector yOnBisector] = closestPointBisector(app, m,c,xOffBisector,yOffBisector)
            % Calculate the nearest point to the line
            if isinf(m) % Vertical line
                xcoord = c; % x = c (line's constant x value)
                ycoord = yOffBisector; % Use the same y-coordinate from the input
            elseif m == 0 % Horizontal line
                xcoord = xOffBisector; % Use the same x-coordinate from the input
                ycoord = c; % y = c (line's constant y value)
            else
                % General case
                xcoord = (xOffBisector + m * (yOffBisector - c)) / (1 + m^2);
                ycoord = m * xcoord + c;
            end

            xOnBisector = xcoord;
            yOnBisector = ycoord;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.calculateClicks;

            app.ScalerSlider.Value = 1;
            app.ScaleValueEditField.Value = 1;

            % Initialize storage for points and snapped circles
            app.coords = [];
            select = 0; % Store snapped circle indices as an array
        
            for a = 1:app.NumberOfClicks
                % Capture one click at a time
                [x, y] = ginput(1);
        
                if isempty(app.coords)
                    % First point
                    app.coords = [x; y; 1];
        
                    scatter(app.UIAxes, x, y, 'filled');
                    hold on


                elseif length(app.coords(1,:)) < 2
                    % Second point
                    newCoords = [x; y; 1];
                    app.coords = [app.coords, newCoords];
        
                    scatter(app.UIAxes, x, y, 'filled');
        
                    % Compute and plot the first circle
                    circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                    app.plotcircle(app.coords(1,1), app.coords(2,1), circle1radius);


                elseif length(app.coords(1,:)) < 3
                    % Third point
                    newCoords = [x; y; 1];
                    app.coords = [app.coords, newCoords];
        
                    scatter(app.UIAxes, x, y, 'filled');
        
                    % Compute and plot the second circle
                    circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));
                    app.plotcircle(app.coords(1,3), app.coords(2,3), circle2radius);


                elseif length(app.coords(1,:)) < 5
                    % Fourth and fifth point
                    circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                    circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));
        
                    % Snap to closest circle
                    [x1, y1, select1] = app.closestPointsOnCircle(app.coords(1,1), app.coords(2,1), circle1radius, app.coords(1,3), app.coords(2,3), circle2radius, x, y);
                    newCoords = [x1; y1; 1];

                    % Check for snapping error
                    if select == select1
                        % Trigger error alert
                        fig = uifigure;
                        uialert(fig, "The 3rd Circle points snapped to the same circle!", "Snapping Error");
                    else
                        % Store snapped circle index
                        select = select1;

                        app.coords = [app.coords, newCoords];
                        scatter(app.UIAxes, x1, y1, 'filled');
                    end


                elseif length(app.coords(1,:)) < 6
                    % Calculate the perpendicular bisector
                    [m, c] = app.centreBisector(app.coords(1,1), app.coords(2,1), app.coords(1,3), app.coords(2,3));

                    [xcoord, ycoord] = app.closestPointBisector(m,c, app.coords(1,end), app.coords(2,end));
                

                    %plot circle 3
                    p1 = [app.coords(1,4), app.coords(2,4)];
                    p2 = [app.coords(1,5), app.coords(2,5)];
                    p3 = [xcoord, ycoord];
                    [xcircle, ycircle, radius] = app.circleSolve(p1, p2, p3);
                    app.plotcircle(xcircle, ycircle, radius);


                    newCoords = [xcoord, xcircle; ycoord, ycircle; 1, 1];
                    app.coords = [app.coords, newCoords];
                
                    % Scatter the point
                    scatter(app.UIAxes, xcoord, ycoord, 'filled');
                    scatter(app.UIAxes, xcircle, ycircle, 'filled');


                    circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                    circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));

                    c1intersection = app.intersections2circles(radius, xcircle, ycircle, circle1radius, app.coords(1,1), app.coords(2,1));
                    c2intersection = app.intersections2circles(radius, xcircle, ycircle, circle2radius, app.coords(1,3), app.coords(2,3));


                    newCoords = [c1intersection(1, :); c1intersection(2, :); 1, 1];
                    app.coords = [app.coords, newCoords];
                    newCoords = [c2intersection(1, :); c2intersection(2, :); 1, 1];
                    app.coords = [app.coords, newCoords];

                    scatter(app.UIAxes, c1intersection(1, :), c1intersection(2, :), 'filled');
                    scatter(app.UIAxes, c2intersection(1, :), c2intersection(2, :), 'filled');
                end
            end
        
            hold off
        end

        % Button pushed function: Transform3CirclesButton
        function Transform3CirclesButtonPushed(app, event)
            if length(app.coords(1,:)) >= 9
                [matrixT, matrixR, matrixCombined] = app.transformationMatrices(app.coords(1,1), app.coords(2,1), app.coords(1,3),app.coords(2,3), app.coords(1,7),app.coords(2,7));


                %get 3 circles radius
                circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));
                circle3radius = app.radiusTangentialCircle(app.coords(1,7),app.coords(2,7), app.coords(1,6),app.coords(2,6));

                transformedPoints = matrixCombined * app.coords;

                %animated transformation
                for t = 0:0.1:1
                    intermediatePoints = (1 - t) * app.coords + t * transformedPoints;

                    scatter(app.UIAxes, intermediatePoints(1, :), intermediatePoints(2, :), 'filled');

                    hold on

                    %plot circles 1, 2 and 3
                    app.plotcircle(intermediatePoints(1, 1), intermediatePoints(2, 1), circle1radius);
                    app.plotcircle(intermediatePoints(1, 3), intermediatePoints(2, 3), circle2radius);
                    app.plotcircle(intermediatePoints(1, 7), intermediatePoints(2, 7), circle3radius);
    
                    hold off
                    pause(0.1);
                end
                app.coords = transformedPoints;
            else
                fig = uifigure;
                uialert(fig, "Cannot transform unless there are 3 circles!", "Transforming Error");
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.coords)
                fid = fopen("save.txt", "w");
    
                for i = 1:length(app.coords(1,:))
                    fprintf(fid, "%.6f %.6f\n", app.coords(1,i), app.coords(2,i)); % Save as floating-point numbers
                end
                fclose(fid);
    
                fig = uifigure;
                uialert(fig, "File Saved!", "File Saved!");
            else
                fig = uifigure;
                uialert(fig, "No coordinates to save!", "Save Error");
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isfile("save.txt")
                fid = fopen("save.txt", "r");
                
                data = fscanf(fid, "%f %f", [2, Inf]); % Read as two columns of floating-point numbers
                fclose(fid);
                
                app.coords = [data(1, :); data(2, :); ones(1, length(data(1, :)))];
                
                %plot coordinates
                scatter(app.UIAxes, app.coords(1,:), app.coords(2,:), 'filled');
                hold on


                if length(app.coords(1,:)) >= 9 
                    circle3radius = app.radiusTangentialCircle(app.coords(1,7),app.coords(2,7), app.coords(1,6),app.coords(2,6));
                    app.plotcircle(app.coords(1,7),app.coords(2,7), circle3radius); %circle 3
                end

                if length(app.coords(1,:)) >= 3
                    circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));
                    app.plotcircle(app.coords(1,3), app.coords(2,3), circle2radius); %circle 2
                end

                if length(app.coords(1,:)) >= 2
                    circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                    app.plotcircle(app.coords(1,1), app.coords(2,1), circle1radius); %circle 1
                end

                hold off

                app.ScalerSlider.Value = 1;
                app.ScaleValueEditField.Value = 1;
        
                %fig = uifigure;
                %uialert(fig, "File Loaded!", "Load Successful");
            else
                % File not found error
                fig = uifigure;
                uialert(fig, "File save.txt not found!", "Load Error");
            end
        end

        % Value changing function: ScalerSlider
        function ScalerSliderValueChanging(app, event)
            changingValue = event.Value;
            
            tempx = app.coords(1,:) * changingValue;
            tempy = app.coords(2,:) * changingValue;

            scatter(app.UIAxes, tempx, tempy, 'filled');
            hold on


            if length(app.coords(1,:)) >= 9 
                circle3radius = app.radiusTangentialCircle(tempx(7), tempy(7), tempx(6), tempy(6));
                app.plotcircle(tempx(7), tempy(7), circle3radius); %circle 3
            end

            if length(app.coords(1,:)) >= 3
                circle2radius = app.radiusTangentialCircle(tempx(3), tempy(3), tempx(2), tempy(2));
                app.plotcircle(tempx(3), tempy(3), circle2radius); %circle 2
            end

            if length(app.coords(1,:)) >= 2
                circle1radius = app.radiusTangentialCircle(tempx(1), tempy(1), tempx(2), tempy(2));
                app.plotcircle(tempx(1), tempy(1), circle1radius); %circle 1
            end

            hold off

            app.ScaleValueEditField.Value = changingValue;
        end

        % Value changed function: ScalerSlider
        function ScalerSliderValueChanged(app, event)
            value = app.ScalerSlider.Value;

            app.coords(1, :) = value * app.coords(1, :);
            app.coords(2, :) = value * app.coords(2, :);

            scatter(app.UIAxes, app.coords(1,:), app.coords(2,:), 'filled');
            hold on


            if length(app.coords(1,:)) >= 9 
                circle3radius = app.radiusTangentialCircle(app.coords(1,7),app.coords(2,7), app.coords(1,6),app.coords(2,6));
                app.plotcircle(app.coords(1,7),app.coords(2,7), circle3radius); %circle 3
            end

            if length(app.coords(1,:)) >= 3
                circle2radius = app.radiusTangentialCircle(app.coords(1,3), app.coords(2,3), app.coords(1,2), app.coords(2,2));
                app.plotcircle(app.coords(1,3), app.coords(2,3), circle2radius); %circle 2
            end

            if length(app.coords(1,:)) >= 2
                circle1radius = app.radiusTangentialCircle(app.coords(1,1), app.coords(2,1), app.coords(1,2), app.coords(2,2));
                app.plotcircle(app.coords(1,1), app.coords(2,1), circle1radius); %circle 1
            end

            hold off

            app.ScaleValueEditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Circle Maker')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.XTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.XTickLabel = {'-100'; '-90'; '-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes.YTick = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NumberofCirclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCirclesEditField.Limits = [1 3];
            app.NumberofCirclesEditField.RoundFractionalValues = 'on';
            app.NumberofCirclesEditField.Value = 1;
            app.NumberofCirclesLabel = uilabel(app.UIFigure);
            app.NumberofCirclesLabel.Text = 'Number of Circles :';
            app.Transform3CirclesButton = uibutton(app.UIFigure, 'push');
            app.Transform3CirclesButton.ButtonPushedFcn = createCallbackFcn(app, @Transform3CirclesButtonPushed, true);
            app.Transform3CirclesButton.Text = 'Transform 3 Circles';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ScalerSliderLabel = uilabel(app.UIFigure);
            app.ScalerSliderLabel.Text = 'Scaler';
            app.ScalerSlider = uislider(app.UIFigure);
            app.ScalerSlider.Limits = [0.1 2];
            app.ScalerSlider.MajorTickLabels = {'0.1', '1.05', '2'};
            app.ScalerSlider.ValueChangedFcn = createCallbackFcn(app, @ScalerSliderValueChanged, true);
            app.ScalerSlider.ValueChangingFcn = createCallbackFcn(app, @ScalerSliderValueChanging, true);
            app.ScalerSlider.Value = 1;
            app.ScaleValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleValueEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Code_23071484

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end